classdef project2_v7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        slider2                       matlab.ui.control.Slider
        Join2Label                    matlab.ui.control.Label
        Joint1Slider                  matlab.ui.control.Slider
        Joint1SliderLabel             matlab.ui.control.Label
        StartServerButton             matlab.ui.control.Button
        ConnectArduinoButton          matlab.ui.control.Button
        EditField_9                   matlab.ui.control.EditField
        EditField_8                   matlab.ui.control.EditField
        EditField_7                   matlab.ui.control.EditField
        EditField_6                   matlab.ui.control.EditField
        EditField_5                   matlab.ui.control.EditField
        EditField_4                   matlab.ui.control.EditField
        EditField_3                   matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.EditField
        EditField_1                   matlab.ui.control.EditField
        RestartGameButton             matlab.ui.control.Button
        StartGameButton               matlab.ui.control.Button
        Button_9                      matlab.ui.control.Button
        Button_8                      matlab.ui.control.Button
        Button_7                      matlab.ui.control.Button
        Button_6                      matlab.ui.control.Button
        Button_5                      matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        OButton                       matlab.ui.control.Button
        XButton                       matlab.ui.control.Button
        DrawGridButton                matlab.ui.control.Button
        PenDOWNButton                 matlab.ui.control.Button
        PenUPButton                   matlab.ui.control.Button
        DistEditField                 matlab.ui.control.NumericEditField
        DistEditFieldLabel            matlab.ui.control.Label
        Joint1Joint2Label             matlab.ui.control.Label
        JointOffsetEditField_2        matlab.ui.control.NumericEditField
        LinktwistalphaEditField_2     matlab.ui.control.NumericEditField
        JointanglethetaEditField_2    matlab.ui.control.NumericEditField
        LinklengthaorrEditField_2     matlab.ui.control.NumericEditField
        LinkOffsetdEditField_2        matlab.ui.control.NumericEditField
        EndEffectorPositionPanel      matlab.ui.container.Panel
        ZEditField                    matlab.ui.control.NumericEditField
        YLabel                        matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        JointOffsetEditField          matlab.ui.control.NumericEditField
        JointOffsetEditFieldLabel     matlab.ui.control.Label
        LinkOffsetdEditField          matlab.ui.control.NumericEditField
        LinkOffsetdEditFieldLabel     matlab.ui.control.Label
        LinktwistalphaEditField       matlab.ui.control.NumericEditField
        LinktwistalphaEditFieldLabel  matlab.ui.control.Label
        LinklengthaorrEditField       matlab.ui.control.NumericEditField
        LinklengthaorrEditFieldLabel  matlab.ui.control.Label
        JointanglethetaEditField      matlab.ui.control.NumericEditField
        JointanglethetaLabel          matlab.ui.control.Label
        CreateRobotButton             matlab.ui.control.Button
        ConfirmparamsButton           matlab.ui.control.Button
        axes1                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        robot = []; % Description
        %arrays used to store dh parameters
%         joint3Angle = -90;
        jointAngles = [0 0]; 
        linkLengths = [1 1];
        linkOffsets = [0 0];
        linkTwists = [0 0];

        jointOffsets = [0 0];
        robotCreated = false;
        T = [];  % Stores the Transformation Matrix of the robot
        %Where the base is positioned on the clipboard
        base = [0 -9 0];

        playerturn = true;
        board = [-1 -1 -1; -1 -1 -1; -1 -1 -1];
        waiting_for_player = true;
        game_on = false;

        Arduino = [];
        Joint_1_Servo = [];
        Joint_2_Servo = [];
        Joint_3_Servo = [];
        Data_Available_bool = false;
        Servo_Transform = [];
        
        % Goes from Joint Angles to Servo Angles
        y1 = @(x1) (-0.00488 * x1) + 0.59;
        y2 = @(x2) (-0.00488 * x2) + 0.23;

        % Goes from Servo Angles to Joint Angles
        y3 = @(x3) (-204.5454 * x3) + 120.6818;
        y4 = @(x4) (-204.5454 * x4) + 47.0454;

        % Server variable
        server = [];
        data = [];
        remote_play = false;
    end
    
    methods (Access = private)
        
        % Returns the position of the end effector using fkine
        function EndEffectorPosition(app)
            if (app.robotCreated)
                app.T = app.robot.fkine(app.jointAngles);
                app.XEditField.Value = app.T.t(1);
                app.YEditField.Value = app.T.t(2);
                app.ZEditField.Value = app.T.t(3);
                app.DistEditField.Value =  hypot(app.T.t(1), app.T.t(2));
                app.JointanglethetaEditField.Value = rad2deg(app.jointAngles(1));
                app.JointanglethetaEditField_2.Value = rad2deg(app.jointAngles(2));
                app.robot.plot (app.jointAngles);

                if app.Data_Available_bool
                    writePosition(app.Joint_1_Servo, app.y1(rad2deg((app.jointAngles(1)))));
                    writePosition(app.Joint_2_Servo, app.y2(rad2deg((app.jointAngles(2)))));
                end
            end
        end
      
        % Move the end effector using the keyboard keys
        function MoveEndEffector(app, key)
                if (strcmp(key, 'd') || strcmp(key, 'rightarrow'))
                    app.T.t(1) = app.T.t(1) + 0.1;
                    app.jointAngles = app.robot.ikcon(app.T, app.jointAngles);
                    EndEffectorPosition(app);
                elseif (strcmp(key, 'a') || strcmp(key, 'leftarrow'))
                    app.T.t(1) = app.T.t(1) - 0.1;
                    app.jointAngles = app.robot.ikcon(app.T, app.jointAngles);
                    EndEffectorPosition(app);
                elseif (strcmp(key, 'w') || strcmp(key, 'uparrow'))
                    app.T.t(2) = app.T.t(2) + 0.1;
                    app.jointAngles = app.robot.ikcon(app.T, app.jointAngles);
                    EndEffectorPosition(app);
                elseif (strcmp(key, 's') || strcmp(key, 'downarrow'))
                    app.T.t(2) = app.T.t(2) - 0.1;
                    app.jointAngles = app.robot.ikcon(app.T, app.jointAngles);
                    EndEffectorPosition(app);
                end     
        end

        %
        function [x, history] = Optimize(~, param)
            %Array to carry all of the error values returned by the average
            %error function
            history = [];
            %Options passed into fminsearch
            options = optimset('OutputFcn', @myoutput);
            [x, fval] = fminsearch(@objfun, param, options);
            
            function stop = myoutput(x, optimvalues, state)
                stop = false;
                if isequal(state,'iter')
                    history = [history; optimvalues.fval];
                end
            end
            %Compares the actual model's values (distance) to the simulated robots 
            %Gives the optimized dh parameters as well
            function avg_error = objfun (param)
                L(1) = Link('revolute', 'd', param(1, 1), 'a', param(1, 2), 'alpha', param(1, 3), 'offset', param(1, 4));
                L(2) = Link('revolute', 'd', param(2, 1), 'a', param(2, 2), 'alpha', param(2, 3), 'offset', param(2, 4));
                jointAngles2 = [0 0];
                robot2 = SerialLink(L, 'name', 'robot2');
                robot2.base = [0 -9 0];
                robot2.plot(jointAngles2);
                
                
                m = readmatrix('EEPos3.txt');  % m is an ARRAY
                mat = [m(1:5); m(6:10); m(11:15)].';  % Matrix of the stuff in m
                diffarr = zeros(1, 5);
    
                for row = 1:5
                    dist_Actual = mat(row, 3);
                    jointAngles2(1) = deg2rad(mat(row, 1));
                    deg2rad(mat(row, 1))
                    jointAngles2(2) = deg2rad(mat(row, 2));
                    %robot2.plot(jointAngles2);
                    pos = robot2.fkine(jointAngles2);
                    %hypot will return the distance of the two points via
                    %the pythagorean theorem
                    dist_model = hypot(pos.t(1), pos.t(2));
                    %differences of the current row
                    diff = abs(dist_Actual - dist_model);
                    %vector of all the differences
                    diffarr(row) = diff;
                end
                %Average of the elements in diffarr
                avg_error = sum(diffarr) / 5;
            end
        end

        % Tic Tac Toe Playing Function
        function ticTacToe(app)
            app.game_on = true;
            app.playerturn = true;
            while app.game_on
                if app.playerturn
                    while app.playerturn
                        % If there is a server client connection, do the
                        % following...
                        if app.remote_play == true
                            try
                                data1 = read(app.server,app.server.NumBytesAvailable,"uint8");
                                data1
                                switch data1
                                    case 1
                                        app.jointAngles = deg2rad([81.8182 -59.3182 ]); % Grid 1
                                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                                        app.robot.plot(app.jointAngles);

                                        if app.board(1,1) == -1
                                            app.EditField_1.Value = 'X';
                                            app.board(1,1) = 1;
                                            app.playerturn = false;
                                            app.Draw_X(rad2deg(app.jointAngles));
                                        end

                                    case 2
                                        app.jointAngles = deg2rad([73 -80 ]); % Grid 2
                                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                                        app.robot.plot(app.jointAngles);

                                        if app.board(1,2) == -1
                                            app.EditField_2.Value = 'X';
                                            app.board(1,2) = 1;
                                            app.playerturn = false;
                                            app.Draw_X(rad2deg(app.jointAngles));
                                        end

                                    case 3
                                        app.jointAngles = deg2rad([54 -80 ]); % Grid 3
                                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                                        app.robot.plot(app.jointAngles);

                                        if app.board(1,3) == -1
                                            app.EditField_3.Value = 'X';
                                            app.board(1,3) = 1;
                                            app.playerturn = false;
                                            app.Draw_X(rad2deg(app.jointAngles));
                                        end

                                    case 4
                                        app.jointAngles = deg2rad([100 -90 ]); % Grid 4
                                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                                        app.robot.plot(app.jointAngles);

                                        if app.board(2,1) == -1
                                            app.EditField_4.Value = 'X';
                                            app.board(2,1) = 1;
                                            app.playerturn = false;
                                            app.Draw_X(rad2deg(app.jointAngles));
                                        end

                                    case 5
                                        app.jointAngles = deg2rad([88 -110 ]); % Grid 5
                                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                                        app.robot.plot(app.jointAngles);

                                        if app.board(2,2) == -1
                                            app.EditField_5.Value = 'X';
                                            app.board(2,2) = 1;
                                            app.playerturn = false;
                                            app.Draw_X(rad2deg(app.jointAngles));
                                        end

                                    case 6
                                        app.jointAngles = deg2rad([60 -115 ]); % Grid 6
                                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                                        app.robot.plot(app.jointAngles);

                                        if app.board(2,3) == -1
                                            app.EditField_6.Value = 'X';
                                            app.board(2,3) = 1;
                                            app.playerturn = false;
                                            app.Draw_X(rad2deg(app.jointAngles));
                                        end

                                    case 7
                                        app.jointAngles = deg2rad([110 -110 ]); % Grid 7
                                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                                        app.robot.plot(app.jointAngles);

                                        if app.board(3,1) == -1
                                            app.EditField_7.Value = 'X';
                                            app.board(3,1) = 1;
                                            app.playerturn = false;
                                            app.Draw_X(rad2deg(app.jointAngles));
                                        end

                                    case 8
                                        app.jointAngles = deg2rad([100 -130 ]); % Grid 8
                                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                                        app.robot.plot(app.jointAngles);
                                        if app.board(3,2) == -1
                                            app.EditField_8.Value = 'X';
                                            app.board(3,2) = 1;
                                            app.playerturn = false;
                                            app.Draw_X(rad2deg(app.jointAngles));
                                        end

                                    case 9
                                        app.jointAngles = deg2rad([55 -143 ]); % Grid 9
                                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                                        app.robot.plot(app.jointAngles);
                                        if app.board(3,3) == -1
                                            app.EditField_9.Value = 'X';
                                            app.board(3,3) = 1;
                                            app.playerturn = false;
                                            app.Draw_X(rad2deg(app.jointAngles));
                                        end
                                end
                            end
                        end
                        pause(2)
                    end
                    app.playerturn = false;
                else  % The following is for the computer player
                    bestMove =  findBestMove(app, app.board);
                    x = bestMove(1);
                    y = bestMove(2);
                    if bestMove ~= -1
                        app.board(x,y) = 0;
                    else
                        return
                    end
                    if x == 1  %First row of grid locations
                        if y == 1 % Row 1 column 1
                            app.EditField_1.Value = 'O';

                            app.jointAngles = deg2rad([81.8182 -59.3182 ]); % Grid 1
                            writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                            writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                            app.robot.plot(app.jointAngles);
                            pause(0.5);
                            app.Draw_O(rad2deg(app.jointAngles));

                        elseif y == 2 % Row 1 column 2
                            app.EditField_2.Value = 'O';

                            app.jointAngles = deg2rad([73 -80 ]); % Grid 2
                            writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                            writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                            app.robot.plot(app.jointAngles);
                            pause(0.5);
                            app.Draw_O(rad2deg(app.jointAngles));

                        else % Row 1 column 3
                            app.EditField_3.Value = 'O';

                            app.jointAngles = deg2rad([54 -80 ]); % Grid 3
                            writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                            writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                            app.robot.plot(app.jointAngles);
                            pause(0.5);
                            app.Draw_O(rad2deg(app.jointAngles));
                        end
                    
                    elseif x == 2 % Row 2 of grid locations
                        if y == 1 % R2 C1
                            app.EditField_4.Value = 'O';

                            app.jointAngles = deg2rad([100 -90 ]); % Grid 4
                            writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                            writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                            app.robot.plot(app.jointAngles);
                            pause(0.5);
                            app.Draw_O(rad2deg(app.jointAngles));

                        elseif y == 2 %R2 C2
                            app.EditField_5.Value = 'O';

                            app.jointAngles = deg2rad([88 -110 ]); % Grid 5
                            writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                            writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                            app.robot.plot(app.jointAngles);
                            pause(0.5);
                            app.Draw_O(rad2deg(app.jointAngles));

                        else % R2 C3
                            app.EditField_6.Value = 'O';

                            app.jointAngles = deg2rad([60 -115 ]); % Grid 6
                            writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                            writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                            app.robot.plot(app.jointAngles);
                            pause(0.5);
                            app.Draw_O(rad2deg(app.jointAngles));
                        end
                    else % 3rd row of grid locations
                        if y == 1 %R3 C1
                            app.EditField_7.Value = 'O';

                            app.jointAngles = deg2rad([115 -110 ]); % Grid 7
                            writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                            writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                            app.robot.plot(app.jointAngles);
                            pause(0.5);
                            app.Draw_O(rad2deg(app.jointAngles));

                        elseif y == 2 %R3 C2
                            app.EditField_8.Value = 'O';

                            app.jointAngles = deg2rad([100 -130 ]); % Grid 8
                            writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                            writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                            app.robot.plot(app.jointAngles);
                            pause(0.5);
                            app.Draw_O(rad2deg(app.jointAngles));

                        else %R3 C3
                            app.EditField_9.Value = 'O';

                            app.jointAngles = deg2rad([55 -143 ]); % Grid 9
                            writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                            writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                            app.robot.plot(app.jointAngles);
                            pause(0.5);
                            app.Draw_O(rad2deg(app.jointAngles));
                        end
                    end

                    app.playerturn = true;
                end
                if Evaluation(app,app.board) ~= 0 || ismember(-1, app.board) == 0  % Check if the game is over / if someone won
                    app.game_on = false;
                    msgbox('Game Over');
                    app.board
                end
      
            end
        end
        %%%%%%%%%%%%%%%%%%%%
        %THE Following code is for the 10% extra credit
        %AI is the minimizer / 0
        %%%%%%%%%%%%%%%%%%%%
        %Evaluation function
        function results = Evaluation(app, board)
            % Assume draw initially 
            results = 0;
            for x = 1:3
                %Checking if there is a winner in the rows
                if board(x,1) == board(x,2) && board(x,2) == board(x,3) 
                    if board(x,1) == 0
                        results = 10;
                        return
                    elseif board(x,1) == 1
                        results = -10;
                        return
                    end
                end
                %Checking if there is a winner in the columns
                if board(1,x) == board(2, x) && board(2, x) == board(3, x)
                    if board(1,x) == 0
                        results = 10;
                        return
                    elseif board(1,x) == 1
                        results = -10;
                        return
                    end
                end    
            end
            %Checking if there is a winner in the diagnol
            if (board(1,1) == board(2,2) && board(2,2) == board(3,3)) 
                if board(2,2) == 0
                    results = 10;
                    return
                elseif board(2,2) == 1
                    results = -10;
                    return
                end
            end
            if (board(1,3) == board(2,2) && board(2,2) == board(3,1)) 
                if board(2,2) == 0
                    results = 10;
                    return
                elseif board(2,2) == 1
                    results = -10;
                    return
                end
            end
        end

        %minimax
        function boardValue = minimax(app,board,depth,isMax)
            boardValue = Evaluation(app, board);
            if boardValue == -10 || boardValue == 10
                return
            end
            if ismember(-1, board) == 0
                boardValue = 0;
                return
            end

            if isMax
                best = -1000;

                for i = 1:3
                    for j = 1:3
                        if board(i,j)==-1
                            board(i,j) = 0;

                            best = max(best, minimax(app,board, depth+1, ~isMax));
                            board(i,j) = -1;
                        end
                    end
                end
                boardValue = best;
                return
            else
                best = 1000;

                for i = 1:3
                    for j = 1:3
                        if board(i,j)==-1
                            board(i,j) = 1;

                            best = min(best, minimax(app,board, depth+1, ~isMax));

                            board(i,j) = -1;
                        end
                    end
                end
                boardValue = best;
                return
            end
        end
        
        % Best move for the computer to play
        function bestMove = findBestMove(app, board)
            bestVal = -1000;
            bestMove = [-1 -1];
            
            % Checks the rows and columns to find the best move in an empty
            % grid spot
            for i = 1:3
                for j = 1:3
                    if board(i,j) == -1
                        board(i, j) = 0;
                        moveVal = minimax(app, board,0, false);
                        board(i,j) = -1;
                        if moveVal > bestVal
                            bestMove = [i j];
                            bestVal = moveVal;
                        end
                    end
                end
            end
        end
        
        % Takes X Center Position and draws an X using 4 lines from that
        % center position
        function Draw_X(app, positions)
            q = 3;
               
            % Line 1
            a = app.y1(2 * 0 + positions(1));
            b = app.y2(2 * 0 + positions(2));
            writePosition(app.Joint_1_Servo, a);
            writePosition(app.Joint_2_Servo, b);
            app.Pen_Down
            for i = 0:q
                a = app.y1(2 * i + positions(1));
                b = app.y2(2 * i + positions(2));
                writePosition(app.Joint_1_Servo, a);
                writePosition(app.Joint_2_Servo, b);
            end
            app.Pen_Up
            
            % Line 2
            a = app.y1(2 * 0 + positions(1));
            b = app.y2(2 * 0 + positions(2));
            writePosition(app.Joint_1_Servo, a);
            writePosition(app.Joint_2_Servo, b);
            app.Pen_Down
            for i = 0:q
                a = app.y1(-2 * i + positions(1));
                b = app.y2(-2 * i + positions(2));
                writePosition(app.Joint_1_Servo, a);
                writePosition(app.Joint_2_Servo, b);
            end
            app.Pen_Up

            % Line 3
            a = app.y1(2 * 0 + positions(1));
            b = app.y2(2 * 0 + positions(2));
            writePosition(app.Joint_1_Servo, a);
            writePosition(app.Joint_2_Servo, b);
            app.Pen_Down
            for i = 0:q
                a2 = app.y1(-2 * i + positions(1));
                b2 = app.y2(2 * i + positions(2));
                writePosition(app.Joint_1_Servo, a2);
                writePosition(app.Joint_2_Servo, b2);
            end
            app.Pen_Up

            % Line 4
            a = app.y1(2 * 0 + positions(1));
            b = app.y2(2 * 0 + positions(2));
            writePosition(app.Joint_1_Servo, a);
            writePosition(app.Joint_2_Servo, b);
            app.Pen_Down
            for i = 0:q
                a2 = app.y1(2 * i + positions(1));
                b2 = app.y2(-2 * i + positions(2));
                writePosition(app.Joint_1_Servo, a2);
                writePosition(app.Joint_2_Servo, b2);
            end
            app.Pen_Up
        end
        
        % Takes the center position as input and draws the O around that
        % position
        function Draw_O(app, positions)
            radius = 5;       
            app.Pen_Down
            for i = 0:5:360
                w = app.y1(radius * cos(deg2rad(i)) + positions(1));
                z = app.y2(radius * sin(deg2rad(i)) + positions(2));
                writePosition(app.Joint_1_Servo, w);
                writePosition(app.Joint_2_Servo, z);
            end
            app.Pen_Up
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConfirmparamsButton
        function ConfirmparamsButtonPushed(app, event)
            if (~app.robotCreated)
                %Confirm the d-h parameters for the first joint
                app.jointAngles(1) = deg2rad(app.JointanglethetaEditField.Value );
                app.linkLengths(1) = app.LinklengthaorrEditField.Value;
                app.linkOffsets(1) = app.LinkOffsetdEditField.Value;
                app.linkTwists(1) = deg2rad(app.LinktwistalphaEditField.Value);
                app.jointOffsets(1) = deg2rad(app.JointOffsetEditField.Value);
                
                %Confirm the d-h parameters for the second joint
                app.jointAngles(2) = deg2rad(app.JointanglethetaEditField_2.Value);
                app.linkLengths(2) = app.LinklengthaorrEditField_2.Value;
                app.linkOffsets(2) = app.LinkOffsetdEditField_2.Value;
                app.linkTwists(2) = deg2rad(app.LinktwistalphaEditField_2.Value); 
                app.jointOffsets(2) = deg2rad(app.JointOffsetEditField_2.Value);
                
                msgbox('Joint Parameters Set');

            elseif (app.robotCreated)
                app.jointAngles(1) = deg2rad(app.JointanglethetaEditField.Value);
                app.jointAngles(2) = deg2rad(app.JointanglethetaEditField_2.Value);
                app.Joint1Slider.Value = rad2deg(app.jointAngles(1));
                app.slider2.Value = rad2deg(app.jointAngles(2));
                app.robot.plot (app.jointAngles);
                EndEffectorPosition(app);
            end
        end

        % Button pushed function: CreateRobotButton
        function CreateRobotButtonPushed(app, event)
            if (~app.robotCreated)
                %If the robot has not been created then pressing this
                %button will create the robot
                L(1) = Link('revolute', 'd', app.linkOffsets(1), 'a', app.linkLengths(1), 'alpha', app.linkTwists(1), 'offset', app.jointOffsets(1) + deg2rad(122));
                L(2) = Link('revolute', 'd', app.linkOffsets(2), 'a', app.linkLengths(2), 'alpha', app.linkTwists(2), 'offset', app.jointOffsets(2));
%                 L(3) = Link('revolute', 'd', 0, 'a', 10, 'alpha', 0, 'offset', 0);
%                 L(1).qlim = [deg2rad(-83) deg2rad(100)];
%                 L(2).qlim = [deg2rad(-157) deg2rad(47)];
%                 L(3).qlim = [0 0];
                app.robot = SerialLink(L, 'name', 'robot');
                app.robot.base = app.base;
                app.robot.plot (app.jointAngles);
                app.LinklengthaorrEditField.Editable = false;
                app.LinkOffsetdEditField.Editable = false;
                app.LinktwistalphaEditField.Editable = false;
                app.JointOffsetEditField.Editable = false;
                app.LinklengthaorrEditField_2.Editable = false;
                app.LinkOffsetdEditField_2.Editable = false;
                app.LinktwistalphaEditField_2.Editable = false;
                app.JointOffsetEditField_2.Editable = false;
                app.robotCreated = true;
                app.CreateRobotButton.Text = 'Optimize';
                EndEffectorPosition(app)  % Get the position of the end effector
            else
                %Once the robot has been created we then turn the creation
                %button into an optimization button
                %  Optimizes the DH-Parameters
                param = [app.linkOffsets(1) app.linkLengths(1) app.linkTwists(1) app.jointOffsets(1); app.linkOffsets(2) app.linkLengths(2) app.linkTwists(2) app.jointOffsets(2)];
                [new_dh, history] = Optimize(app, param);
                plot(app.axes1, history)
                close all;
                
                %  Plots the robot again with the new DH-Parameters
                L(1) = Link('revolute', 'd', new_dh(1, 1), 'a', new_dh(1, 2), 'alpha', new_dh(1, 3), 'offset', new_dh(1, 4));
                L(2) = Link('revolute', 'd', new_dh(2, 1), 'a', new_dh(2, 2), 'alpha', new_dh(2, 3), 'offset', new_dh(2, 4));
                L(3) = Link('revolute', 'd', 0, 'a', 10, 'alpha', 0, 'offset', 0);
                L(1).qlim = [deg2rad(-83) deg2rad(100)];
                L(2).qlim = [deg2rad(-157) deg2rad(47)];
                app.robot = SerialLink(L, 'name', 'robot');
                app.robot.base = app.base;
                app.robot.plot (app.jointAngles);
            end            
        end

        % Value changed function: Joint1Slider
        function slider1ValueChanged(app, event)
            if (app.robotCreated)
                %get the values of the sliders from each joint and move the
                %servos to that location
                app.jointAngles(1) = app.Joint1Slider.Value;
                writePosition(app.Joint_1_Servo, app.y1((app.jointAngles(1))));
                app.jointAngles(1) = deg2rad(app.jointAngles(1));
                EndEffectorPosition(app);
            else
                msgbox('Please create the robot first.');
            end
        end

        % Value changed function: slider2
        function slider2ValueChanged(app, event)
            if (app.robotCreated)
                %get the values of the sliders from each joint and move the
                %servos to that location
                app.jointAngles(2) = app.slider2.Value;
                writePosition(app.Joint_2_Servo, app.y2((app.jointAngles(2))));
                app.jointAngles(2) = deg2rad(app.jointAngles(2));
                EndEffectorPosition(app);
            else
                msgbox('Please create the robot first.');
            end
        end

        % Key press function: UIFigure
        function KeyPress(app, event)
            key = event.Key;
            if (app.robotCreated)
                MoveEndEffector(app, key);
            end
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            ticTacToe(app)
        end

        % Button pushed function: Button, Button_2, Button_3, Button_4, 
        % ...and 7 other components
        function ButtonPushed(app, event)
            event.Source.Tag % Which button the user pushed
            
            % This code is to move the pen to a grid position or draw an
            %  X / O when NOT playing a game
            switch event.Source.Tag
                case '1'
                    app.jointAngles = deg2rad([81.8182 -59.3182 ]); % Grid 1
                    writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                    writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                    app.robot.plot(app.jointAngles);

                case '2'
                    app.jointAngles = deg2rad([73 -80 ]); % Grid 2
                    writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                    writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                    app.robot.plot(app.jointAngles);

                case '3'
                    app.jointAngles = deg2rad([54 -80 ]); % Grid 3
                    writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                    writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                    app.robot.plot(app.jointAngles);

                case '4'
                    app.jointAngles = deg2rad([100 -90 ]); % Grid 4
                    writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                    writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                    app.robot.plot(app.jointAngles);

                case '5'
                    app.jointAngles = deg2rad([88 -110 ]); % Grid 5
                    writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                    writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                    app.robot.plot(app.jointAngles);

                case '6'
                    app.jointAngles = deg2rad([60 -115 ]); % Grid 6
                    writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                    writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                    app.robot.plot(app.jointAngles);

                case '7'
                    app.jointAngles = deg2rad([115 -110 ]); % Grid 7
                    writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                    writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                    app.robot.plot(app.jointAngles);

                case '8'
                    app.jointAngles = deg2rad([100 -130 ]); % Grid 8
                    writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                    writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                    app.robot.plot(app.jointAngles);

                case '9'
                    app.jointAngles = deg2rad([55 -143 ]); % Grid 9
                    writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                    writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                    app.robot.plot(app.jointAngles);

                case 'X'
                    app.Draw_X(rad2deg(app.jointAngles));;

                case 'O'
                    app.Draw_O(rad2deg(app.jointAngles));;
            end

            % This code is to move the pen to a grid location when PLAYING
            % A GAME, it will automatically draw the X (the computer is O)
            if app.game_on && app.waiting_for_player && ~app.remote_play
                switch event.Source.Tag
                    case '1'
                        app.jointAngles = deg2rad([81.8182 -59.3182 ]); % Grid 1
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);
                        
                        if app.board(1,1) == -1
                            app.EditField_1.Value = 'X';
                            app.board(1,1) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '2'
                        app.jointAngles = deg2rad([73 -80 ]); % Grid 2
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);
                        
                        if app.board(1,2) == -1
                            app.EditField_2.Value = 'X';
                            app.board(1,2) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '3'
                        app.jointAngles = deg2rad([54 -80 ]); % Grid 3
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);

                        if app.board(1,3) == -1
                            app.EditField_3.Value = 'X';
                            app.board(1,3) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '4'
                        app.jointAngles = deg2rad([100 -90 ]); % Grid 4
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);

                        if app.board(2,1) == -1
                            app.EditField_4.Value = 'X';
                            app.board(2,1) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '5'
                        app.jointAngles = deg2rad([88 -110 ]); % Grid 5
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);

                        if app.board(2,2) == -1
                            app.EditField_5.Value = 'X';
                            app.board(2,2) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '6'
                        app.jointAngles = deg2rad([60 -115 ]); % Grid 6
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);

                        if app.board(2,3) == -1
                            app.EditField_6.Value = 'X';
                            app.board(2,3) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '7'
                        app.jointAngles = deg2rad([110 -110 ]); % Grid 7
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);

                        if app.board(3,1) == -1
                            app.EditField_7.Value = 'X';
                            app.board(3,1) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '8'
                        app.jointAngles = deg2rad([100 -130 ]); % Grid 8
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);
                        if app.board(3,2) == -1
                            app.EditField_8.Value = 'X';
                            app.board(3,2) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '9'
                        app.jointAngles = deg2rad([55 -143 ]); % Grid 9
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);
                        if app.board(3,3) == -1
                            app.EditField_9.Value = 'X';
                            app.board(3,3) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end
                end

            % This is for remote play, it recieves the data (grid position) 
            % from the client and places an X in that location
            elseif app.game_on && app.waiting_for_player && app.remote_play
                event.Source.Tag = app.data;
                switch event.Source.Tag
                    case '1'
                        app.jointAngles = deg2rad([81.8182 -59.3182 ]); % Grid 1
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);
                        
                        if app.board(1,1) == -1
                            app.EditField_1.Value = 'X';
                            app.board(1,1) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '2'
                        app.jointAngles = deg2rad([73 -80 ]); % Grid 2
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);
                        
                        if app.board(1,2) == -1
                            app.EditField_2.Value = 'X';
                            app.board(1,2) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '3'
                        app.jointAngles = deg2rad([54 -80 ]); % Grid 3
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);

                        if app.board(1,3) == -1
                            app.EditField_3.Value = 'X';
                            app.board(1,3) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '4'
                        app.jointAngles = deg2rad([100 -90 ]); % Grid 4
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);

                        if app.board(2,1) == -1
                            app.EditField_4.Value = 'X';
                            app.board(2,1) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '5'
                        app.jointAngles = deg2rad([88 -110 ]); % Grid 5
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);

                        if app.board(2,2) == -1
                            app.EditField_5.Value = 'X';
                            app.board(2,2) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '6'
                        app.jointAngles = deg2rad([60 -115 ]); % Grid 6
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);

                        if app.board(2,3) == -1
                            app.EditField_6.Value = 'X';
                            app.board(2,3) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '7'
                        app.jointAngles = deg2rad([115 -110 ]); % Grid 7
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);

                        if app.board(3,1) == -1
                            app.EditField_7.Value = 'X';
                            app.board(3,1) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '8'
                        app.jointAngles = deg2rad([100 -130 ]); % Grid 8
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);
                        if app.board(3,2) == -1
                            app.EditField_8.Value = 'X';
                            app.board(3,2) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end

                    case '9'
                        app.jointAngles = deg2rad([55 -143 ]); % Grid 9
                        writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                        writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                        app.robot.plot(app.jointAngles);
                        if app.board(3,3) == -1
                            app.EditField_9.Value = 'X';
                            app.board(3,3) = 1;
                            app.playerturn = false;
                            app.Draw_X(rad2deg(app.jointAngles));
                        end
                end

                app.board
            end

        end

        % Button pushed function: DrawGridButton
        function Draw_Grid(app, event)
            LineValues = load('LineValues.mat');  % Line start / end / intermediate X & Y Positions
            
            % Draws the first line starting from the positions listed in
            % the file
            app.jointAngles = deg2rad([73.6364 -59.3182 ]); % Line1 X1 Y1
%             app.jointAngles = deg2rad([120.6818 -126.8182 ]); % Line1 X2 Y2
            writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
            writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
            pause(0.5);
            app.Pen_Down;
            for i = 1:2:length(LineValues.X_L1)
                app.T = app.robot.fkine(app.jointAngles);
                app.T.t(1) = LineValues.X_L1(i);
                app.T.t(2) = LineValues.Y_L1(i);
                app.jointAngles = app.robot.ikcon(app.T, app.jointAngles);
                writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                app.robot.plot(app.jointAngles);
            end
            app.Pen_Up;
            pause(0.5);
            
            % Draws line 2 from the positions listed in the file
            app.jointAngles = deg2rad([61.3636 -73.6364 ]); % Line2 X1 Y1
%             app.jointAngles = deg2rad([79.7727 -157.50 ]); % Line2 X2 Y2
            writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
            writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
            pause(0.5);
            app.Pen_Down;
            for i = 1:2:length(LineValues.X_L2)
                app.T = app.robot.fkine(app.jointAngles);
                app.T.t(1) = LineValues.X_L2(i);
                app.T.t(2) = LineValues.Y_L2(i);
                app.jointAngles = app.robot.ikcon(app.T, app.jointAngles);
                writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                app.robot.plot(app.jointAngles);
            end
            app.Pen_Up;
            pause(0.5);

            % Draws line 3 from the positions listed in the file
            app.jointAngles = deg2rad([49.0909 -96.1364 ]); % Line3 X1 Y1
%             app.jointAngles = deg2rad([90 -55.2273 ]); % Line3 X2 Y2
            writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
            writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
            pause(0.5);
            app.Pen_Down;
            for i = 1:2:length(LineValues.X_L3)
                app.T = app.robot.fkine(app.jointAngles);
                app.T.t(1) = LineValues.X_L3(i);
                app.T.t(2) = LineValues.Y_L3(i);
                app.jointAngles = app.robot.ikcon(app.T, app.jointAngles);
                writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                app.robot.plot(app.jointAngles);
            end
            app.Pen_Up;
            pause(0.5);

            % Draws line 4 from the positions listed in the file
            app.jointAngles = deg2rad([45 -122.7273 ]); % Line4 X1 Y1
%             app.jointAngles = deg2rad([106.3636 -81.8182 ]); % Line4 X2 Y2
            writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
            writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
            pause(0.5);
            app.Pen_Down;
            for i = 1:2:length(LineValues.X_L4)
                app.T = app.robot.fkine(app.jointAngles);
                app.T.t(1) = LineValues.X_L4(i);
                app.T.t(2) = LineValues.Y_L4(i);
                app.jointAngles = app.robot.ikcon(app.T, app.jointAngles);
                writePosition(app.Joint_1_Servo, app.y1(rad2deg(app.jointAngles(1))));
                writePosition(app.Joint_2_Servo, app.y2(rad2deg(app.jointAngles(2))));
                app.robot.plot(app.jointAngles);
            end
            app.Pen_Up;
            pause(0.5);
        end

        % Button pushed function: ConnectArduinoButton
        function Connect_Arduino(app, event)
            Port = char(inputdlg('Enter Arduino Port:', 'Port Select', 1, {'COM3'}));
            try
                % Try to connect the arduino and the servos, return error
                % if failed
                app.Arduino = arduino(Port, 'Uno', 'Libraries', 'Servo');
%                 app.Joint_1_Servo = servo(app.Arduino, 'D9', 'MaxPulseDuration', 2.1e-3, 'MinPulseDuration', 0.9e-3);
%                 app.Joint_2_Servo = servo(app.Arduino, 'D10', 'MaxPulseDuration', 2.1e-3, 'MinPulseDuration', 0.9e-3);
%                 app.Joint_3_Servo = servo(app.Arduino, 'D11', 'MaxPulseDuration', 2460e-6, 'MinPulseDuration', 575e-6);

                app.Joint_1_Servo = servo(app.Arduino, 'D9');
                app.Joint_2_Servo = servo(app.Arduino, 'D10');
                app.Joint_3_Servo = servo(app.Arduino, 'D11', 'MaxPulseDuration', 2460e-6, 'MinPulseDuration', 575e-6);

                writePosition(app.Joint_1_Servo, app.y1(0));
                writePosition(app.Joint_2_Servo, app.y2(0));
            catch
                msgbox('Port Connection Failed',Port,'Error');  
                return;
            end
            app.Data_Available_bool = true;
            msgbox('Port Successfully Connected');
        end

        % Button pushed function: PenUPButton
        function Pen_Up(app, event)
            writePosition(app.Joint_3_Servo, 0.6);
        end

        % Button pushed function: PenDOWNButton
        function Pen_Down(app, event)
            writePosition(app.Joint_3_Servo, 0);
        end

        % Button pushed function: RestartGameButton
        function RestartGame(app, event)
            app.playerturn = true;
            app.board = [-1 -1 -1; -1 -1 -1; -1 -1 -1];
            app.game_on = false;
            app.EditField_1.Value = '';
            app.EditField_2.Value = '';
            app.EditField_3.Value = '';
            app.EditField_4.Value = '';
            app.EditField_5.Value = '';
            app.EditField_6.Value = '';
            app.EditField_7.Value = '';
            app.EditField_8.Value = '';
            app.EditField_9.Value = '';
            ticTacToe(app)
        end

        % Button pushed function: StartServerButton
        function Start_Server(app, event)
            [~,hostname] = system('hostname');
            hostname = string(strtrim(hostname))
            address = resolvehost(hostname,"address")
            
            app.server = tcpserver('localhost',5000,"Timeout",30);

            
            %app.server = tcpip('0.0.0.0', 20000, 'NetworkRole', 'server');
            %fopen(app.server);
            app.remote_play = true;
            %app.data = fread(app.server, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPress, true);
            app.UIFigure.Tag = 'figure1';
            app.axes1 = uiaxes(app.UIFigure);
            xlabel(app.axes1, {'Iteration'; ''})
            ylabel(app.axes1, 'Average Error')
            app.axes1.Tag = 'axes1';
            app.ConfirmparamsButton = uibutton(app.UIFigure, 'push');
            app.ConfirmparamsButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmparamsButtonPushed, true);
            app.ConfirmparamsButton.Text = 'Confirm params';
            app.CreateRobotButton = uibutton(app.UIFigure, 'push');
            app.CreateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRobotButtonPushed, true);
            app.CreateRobotButton.Text = 'Create Robot';
            app.JointanglethetaLabel = uilabel(app.UIFigure);
            app.JointanglethetaLabel.Text = 'Joint angle (theta)';
            app.JointanglethetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinklengthaorrEditFieldLabel = uilabel(app.UIFigure);
            app.LinklengthaorrEditFieldLabel.Text = 'Link length (a or r)';
            app.LinklengthaorrEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinklengthaorrEditField.Value = 11;
            app.LinktwistalphaEditFieldLabel = uilabel(app.UIFigure);
            app.LinktwistalphaEditFieldLabel.Text = 'Link twist (alpha)';
            app.LinktwistalphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinkOffsetdEditFieldLabel = uilabel(app.UIFigure);
            app.LinkOffsetdEditFieldLabel.Text = 'Link Offset (d)';
            app.LinkOffsetdEditField = uieditfield(app.UIFigure, 'numeric');
            app.JointOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.JointOffsetEditFieldLabel.Text = 'Joint Offset';
            app.JointOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.JointOffsetEditField.Value = -30;
            app.EndEffectorPositionPanel = uipanel(app.UIFigure);
            app.EndEffectorPositionPanel.Title = 'End Effector Position';
            app.XEditFieldLabel = uilabel(app.EndEffectorPositionPanel);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.EndEffectorPositionPanel, 'numeric');
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.EndEffectorPositionPanel);
            app.YEditFieldLabel.Text = {'Y: '; ''};
            app.YEditField = uieditfield(app.EndEffectorPositionPanel, 'numeric');
            app.YEditField.Editable = 'off';
            app.YLabel = uilabel(app.EndEffectorPositionPanel);
            app.YLabel.Text = {'Z:'; ''};
            app.ZEditField = uieditfield(app.EndEffectorPositionPanel, 'numeric');
            app.ZEditField.Editable = 'off';
            app.LinkOffsetdEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LinklengthaorrEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LinklengthaorrEditField_2.Value = 9;
            app.JointanglethetaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LinktwistalphaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LinktwistalphaEditField_2.Value = 90;
            app.JointOffsetEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.JointOffsetEditField_2.Value = -5;
            app.Joint1Joint2Label = uilabel(app.UIFigure);
            app.Joint1Joint2Label.Text = 'Joint 1                   Joint 2';
            app.DistEditFieldLabel = uilabel(app.UIFigure);
            app.DistEditFieldLabel.Text = 'Dist:';
            app.DistEditField = uieditfield(app.UIFigure, 'numeric');
            app.PenUPButton = uibutton(app.UIFigure, 'push');
            app.PenUPButton.ButtonPushedFcn = createCallbackFcn(app, @Pen_Up, true);
            app.PenUPButton.Text = 'Pen UP';
            app.PenDOWNButton = uibutton(app.UIFigure, 'push');
            app.PenDOWNButton.ButtonPushedFcn = createCallbackFcn(app, @Pen_Down, true);
            app.PenDOWNButton.Text = 'Pen DOWN';
            app.DrawGridButton = uibutton(app.UIFigure, 'push');
            app.DrawGridButton.ButtonPushedFcn = createCallbackFcn(app, @Draw_Grid, true);
            app.DrawGridButton.Text = 'Draw Grid';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.XButton.Tag = 'X';
            app.XButton.Text = 'X';
            app.OButton = uibutton(app.UIFigure, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.OButton.Tag = 'O';
            app.OButton.Text = 'O';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tag = '1';
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Tag = '2';
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_3.Tag = '3';
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_4.Tag = '4';
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_5.Tag = '5';
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_6.Tag = '6';
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_7.Tag = '7';
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_8.Tag = '8';
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_9.Tag = '9';
            app.Button_9.Text = '9';
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.RestartGameButton = uibutton(app.UIFigure, 'push');
            app.RestartGameButton.ButtonPushedFcn = createCallbackFcn(app, @RestartGame, true);
            app.RestartGameButton.Text = 'Restart Game';
            app.EditField_1 = uieditfield(app.UIFigure, 'text');
            app.EditField_1.Editable = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            app.EditField_9.Editable = 'off';
            app.ConnectArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnectArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @Connect_Arduino, true);
            app.ConnectArduinoButton.Text = 'Connect Arduino';
            app.StartServerButton = uibutton(app.UIFigure, 'push');
            app.StartServerButton.ButtonPushedFcn = createCallbackFcn(app, @Start_Server, true);
            app.StartServerButton.Text = 'Start Server';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-83 100];
            app.Joint1Slider.MajorTicks = [-83 -40 0 50 100];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @slider1ValueChanged, true);
            app.Join2Label = uilabel(app.UIFigure);
            app.Join2Label.Text = {'Joint 2'; ''};
            app.slider2 = uislider(app.UIFigure);
            app.slider2.Limits = [-157 47];
            app.slider2.MajorTicks = [-157 0 47];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @slider2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project2_v7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end